classdef ecualizador_SergioSebasv1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RestablecerKnobsButton          matlab.ui.control.Button
        ValoresPredefinidosButtonGroup  matlab.ui.container.ButtonGroup
        LoungeButton                    matlab.ui.control.ToggleButton
        VocesButton                     matlab.ui.control.ToggleButton
        ClasicaButton                   matlab.ui.control.ToggleButton
        AltosButton                     matlab.ui.control.ToggleButton
        MediosButton                    matlab.ui.control.ToggleButton
        GravesButton                    matlab.ui.control.ToggleButton
        PredeterminadoButton            matlab.ui.control.ToggleButton
        SelectordeEQButtonGroup         matlab.ui.container.ButtonGroup
        EQ10Button                      matlab.ui.control.RadioButton
        EQ3Button                       matlab.ui.control.RadioButton
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        SliderPasabanda10               matlab.ui.control.Slider
        SliderPasabanda9                matlab.ui.control.Slider
        SliderPasabanda8                matlab.ui.control.Slider
        SliderPasabanda7                matlab.ui.control.Slider
        SliderPasabanda6                matlab.ui.control.Slider
        SliderPasabanda5                matlab.ui.control.Slider
        SliderPasabanda4                matlab.ui.control.Slider
        SliderPasabanda3                matlab.ui.control.Slider
        SliderPasabanda2                matlab.ui.control.Slider
        SliderPasabanda1                matlab.ui.control.Slider
        AltosKnob                       matlab.ui.control.Knob
        AltosKnobLabel                  matlab.ui.control.Label
        MediosKnob                      matlab.ui.control.Knob
        MediosKnobLabel                 matlab.ui.control.Label
        BajosKnob                       matlab.ui.control.Knob
        BajosKnobLabel                  matlab.ui.control.Label
        BalanceSlider                   matlab.ui.control.Slider
        BalanceSliderLabel              matlab.ui.control.Label
        CargarPistaButton               matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
        properties (Access = private)
            pistaOriginal;
            FS = 0; % Frecuencia de muestreo
            control
            muestra = 1;
            isPlaying = false;
            isPaused = false;
            cancion
        end
        
        methods (Access = private)
        
            % Función del ecualizador que selecciona entre dos modos (EQ 3 o EQ 10)
            function [senalSalida, resp_frec, frec_ang] = FuncEqualizador(app)
                % Verifica si el selector de ecualización está en modo 'EQ 3'
                if strcmp(app.SelectordeEQButtonGroup.SelectedObject.Text, 'EQ 3')
                    % Convierte las ganancias de los controles de bajos, medios y altos de dB a escala lineal
                    GananciaSliders = [10^(app.BajosKnob.Value/20), 10^(app.MediosKnob.Value/20), 10^(app.AltosKnob.Value/20)];
                                        
                    % Aplica el ecualizador de 3 bandas con las ganancias seleccionadas
                    [senalFiltrada, resp_frec, frec_ang] = FuncEQ3(app, GananciaSliders);
    
                    %ajuste de los canales de audio
                    BalanceIzq =  1 - max(0, app.BalanceSlider.Value);
                    BalanceDer = 1 - max(0, -app.BalanceSlider.Value);
    
    
                senalSalida = [senalFiltrada(:, 1) * BalanceIzq, senalFiltrada(:, 2) * BalanceDer];

            
                % Verifica si el selector de ecualización está en modo 'EQ 10'
                elseif strcmp(app.SelectordeEQButtonGroup.SelectedObject.Text, 'EQ 10')
                    % Convierte las ganancias de los sliders de cada banda pasabanda de dB a escala lineal
                    GananciaSliders = [10^(app.SliderPasabanda1.Value/20), 10^(app.SliderPasabanda2.Value/20), ...
                                       10^(app.SliderPasabanda3.Value/20), 10^(app.SliderPasabanda4.Value/20), ...
                                       10^(app.SliderPasabanda5.Value/20), 10^(app.SliderPasabanda6.Value/20), ...
                                       10^(app.SliderPasabanda7.Value/20), 10^(app.SliderPasabanda8.Value/20), ...
                                       10^(app.SliderPasabanda9.Value/20), 10^(app.SliderPasabanda10.Value/20)];
                    
                    % Aplica el ecualizador de 10 bandas con las ganancias seleccionadas
                    [senalFiltrada, resp_frec, frec_ang] = FuncEQ10(app, GananciaSliders);
    
                    %ajuste de los canales de audio
                    BalanceIzq =  1 - max(0, app.BalanceSlider.Value);
                    BalanceDer = 1 - max(0, -app.BalanceSlider.Value);


                senalSalida = [senalFiltrada(:, 1) * BalanceIzq, senalFiltrada(:, 2) * BalanceDer];

                end
            
                
            end


            % Función para aplicar el filtro de 10 bandas
            function [senalSalida, resp_frec, frec_ang] = FuncEQ10(app, Gnc)
                syms s r z
                M = 4; % Orden del filtro
            
                % Frecuencias de corte (Fc) en Hz
                Fc = [0, 130, 200, 300, 600, 1200, 2400, 4800, 9600, 15360];
            
                % Anchos de banda (FB) en Hz
                FB = [210, 350, 350, 400, 800, 1600, 3200, 3200, 6400, 6400];
            
                % Inicialización de señales
                senal_canal_izq = app.pistaOriginal(:, 1); % Canal izquierdo
                senal_canal_dch = app.pistaOriginal(:, 2); % Canal derecho
            
                resp_frec = []; % Respuesta en frecuencia
            
                % Calcula la frecuencia angular una vez
                [~, frec_ang] = freqz(1, 1, 1024, app.FS);
            
                % Procesamiento para cada frecuencia de corte
                for indice = 1:length(Gnc)
                    G = Gnc(indice); % Ganancia de la banda
                    wB = 2 * pi * FB(indice) / app.FS; % Ancho de banda digital
                    wc = 2 * pi * Fc(indice) / app.FS; % Frecuencia de corte digital
            
                    % Filtro pasa bajo analógico
                    HPB = 1;
                    bt = G^(1/M); % Factor de escala
                    for m = 1:M
                        am = (0.5 - (2*m - 1)/(2*M)) * pi;
                        HPB = HPB * (s + bt * exp(1i*am)) / (s + exp(1i*am));
                    end
            
                    % Digitalización del filtro pasa bajo
                    K = tan(wB/2);
                    q = (1 - r^(-1)) / (K * (1 + r^(-1)));
                    HPBd = simplifyFraction(subs(HPB, s, q), 'Expand', true);
            
                    % Conversión a filtro pasa banda
                    v = z * (cos(wc) - z) / (1 - z * cos(wc));
                    HPBNz = simplifyFraction(subs(HPBd, r, v), 'Expand', true);
            
                    % Filtrado de señales
                    [numerador, denominador] = numden(HPBNz);
                    numerador = sym2poly(numerador);
                    denominador = sym2poly(denominador);
                    
                    if isempty(numerador)
                        numerador = 1;
                    end
                    if isempty(denominador)
                        denominador = 1;
                    end
            
                    % Respuesta en frecuencia para cada banda
                    [Hi, ~] = freqz(numerador, denominador, 1024, app.FS); 
                    resp_frec(:, indice) = Hi; 
            
                    % Aplicar el filtro a las señales
                    salida_izq = real(filter(numerador, denominador, senal_canal_izq));
                    salida_dch = real(filter(numerador, denominador, senal_canal_dch));
            
                    rms_izq = sqrt(mean(salida_izq.^2));
                    rms_dch = sqrt(mean(salida_dch.^2));
            
                    salida_izq = salida_izq / rms_izq;
                    salida_dch = salida_dch / rms_dch;
            
                    factor_normalizacion = 0.8;
                    salida_izq = salida_izq * factor_normalizacion;
                    salida_dch = salida_dch * factor_normalizacion;
            
                    ganancia_efecto = 1;
                    salida_izq = salida_izq * ganancia_efecto;
                    salida_dch = salida_dch * ganancia_efecto;
            
                    senal_canal_izq = salida_izq;
                    senal_canal_dch = salida_dch;
                end
            
                senalSalida = [senal_canal_izq, senal_canal_dch];
            end


            %Función  para el equalizador de 3 bandas
            function [senalSalida, resp_frec, frec_ang] = FuncEQ3(app, Gnc)
                syms s r z
                M = 4; % Orden del filtro
            
                % Inicializando filtros y frecuencias de banda
                FB = [300, 850, 4000]; % Frecuencias de banda en Hz
                Fc = [150, 1150, 5000]; % Frecuencias de corte en Hz
                
                % Inicialización de señales
                senal_canal_izq = app.pistaOriginal(:, 1); % Canal izquierdo
                senal_canal_dch = app.pistaOriginal(:, 2); % Canal derecho
            
                for indice = 1:length(Gnc)
                    G = Gnc(indice);

                    % FILTRO PASA BAJO O PASA BANDA
                    FB = [300, 850, 4000];
                    Fc = [150, 1150, 5000];
                    wB = 2 * pi * FB(indice) / app.FS;
                    wc = 2 * pi * Fc(indice) / app.FS;
    
                    % FILTRO SHELVING ANALOGICO
                    HPB = 1;
                    bt = G^(1/M); %  ecuacion 7
                    for m = 1:M
                        am = (0.5 - (2*m - 1)/(2*M)) * pi; % ecuación 8
                        HPB = HPB * (s + bt * exp(j*am)) / (s + exp(j*am)); % función de transferencia filtro Pasa bajos
                    end
    
                    % DIGITALIZACION FILTRO PASA BAJO
                    K = tan(wB/2);
                    q = (1 - r^(-1)) / (K * (1 + r^(-1))); %ecuación 11
                    HPBd = simplifyFraction(subs(HPB, s, q), 'Expand', true);
    
                    if indice == 1
                        PasaBajo = HPBd;
                    elseif indice == 2
                        % CONVERSION DE PASABAJO A PASABANDA
                        v = z * (cos(wc) - z) / (1 - z * cos(wc)); % ecuacion 13
                        % queda en funcion de z
                        HPBNz1 = simplifyFraction(subs(HPBd, r, v), 'Expand', true);
                        PasaBanda = HPBNz1;
                    else
                        %filtro pasa alto ecuacion 15
                        HPBNz2 = simplifyFraction(subs(HPBd, r, -v), 'Expand', true);
                        PasaAlto = HPBNz2;
                    end

                end
            
                % Inicialización de filtros
                filtros = {PasaBajo, PasaBanda, PasaAlto};


                resp_frec = [];
                frec_ang = [];
            
                % Procesamiento de cada banda
                for k = 1:3
                    [n, d] = numden(filtros{k});
                    numerador = sym2poly(n);
                    denominador = sym2poly(d);

                    % Respuesta en frecuencia
                    [Hi, wi] = freqz(numerador, denominador, 10000);
                    resp_frec = [resp_frec, Hi]; % Acumular la respuesta en frecuencia de cada filtro
                    frec_ang = [frec_ang, wi]; % Acumular las frecuencias angulares de cada filtro
            
                    % Filtrado de señales
                    salida_izq = real(filter(numerador, denominador, senal_canal_izq));
                    salida_dch = real(filter(numerador, denominador, senal_canal_dch));
            
                    % Normalización basada en RMS (más suave que el máximo absoluto)
                    rms_izq = sqrt(mean(salida_izq.^2));
                    rms_dch = sqrt(mean(salida_dch.^2));
            
                    salida_izq = salida_izq / rms_izq; % Normalización RMS
                    salida_dch = salida_dch / rms_dch; % Normalización RMS
            
                    % Aplicar un factor de normalización menos agresivo
                    factor_normalizacion = 0.8; % Ajusta según lo que necesites
                    salida_izq = salida_izq * factor_normalizacion;
                    salida_dch = salida_dch * factor_normalizacion;
            
                    % Ajuste manual de ganancia adicional para enfatizar los efectos del filtro
                    ganancia_efecto = 1; % Ajusta según lo que necesites
                    salida_izq = salida_izq * ganancia_efecto;
                    salida_dch = salida_dch * ganancia_efecto;
            
                    % Señal filtrada de entrada al siguiente filtro
                    senal_canal_izq = salida_izq;
                    senal_canal_dch = salida_dch;
                end
            
                % Señal de salida combinada
                senalSalida = [senal_canal_izq, senal_canal_dch];
            end


            % Función para manejar el evento de ecualización y reproducción
            function evento(app)
                switch app.SelectordeEQButtonGroup.SelectedObject.Text
                    case 'EQ 3'
                        if isempty(app.cancion)
                            return;
                        else
                            [sonido_rep, resp_frec, frec_ang] = FuncEqualizador(app);
                            app.cancion = audioplayer(sonido_rep, app.FS);
    
                            % Actualizar gráfica
                            cla(app.UIAxes, 'reset');
                            cla(app.UIAxes_2, 'reset');
                            % Graficar la respuesta en frecuencia
                            % Mantener las gráficas anteriores
                            hold(app.UIAxes_2, 'on');
                    
                            % Aquí se puede usar diferentes estilos de línea o colores para diferenciar cada traza
                            % Ejemplo para graficar las respuestas de cada banda
                            num_bandas = 3;
                            colores = lines(num_bandas); % Genera una matriz de colores
                    
                            for i = 1:num_bandas
                                plot(app.UIAxes_2,log10(frec_ang), 20*log10(abs(resp_frec(:,i))), 'LineWidth', 2, 'Color', colores(i,:));
                            end
                        end
                    case 'EQ 10'
                        if isempty(app.cancion)
                            return;
                        else
                            [sonido_rep, resp_frec, frec_ang] = FuncEqualizador(app);
                            app.cancion = audioplayer(sonido_rep, app.FS);
    
                            % Actualizar gráfica
                            cla(app.UIAxes, 'reset');
                            cla(app.UIAxes_2, 'reset');
                            % Graficar la respuesta en frecuencia
                            % Mantener las gráficas anteriores
                            hold(app.UIAxes, 'on');
                    
                            % Aquí se puede usar diferentes estilos de línea o colores para diferenciar cada traza
                            % Ejemplo para graficar las respuestas de cada banda
                            num_bandas = 10;
                            colores = lines(num_bandas); % Genera una matriz de colores
                    
                            for i = 1:num_bandas
                                plot(app.UIAxes, log10(frec_ang), 20*log10(abs(resp_frec(:,i))), 'LineWidth', 2, 'Color', colores(i,:));
                            end
                        end
                end
            end
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarPistaButton
        function CargarCancion(app, event)
            
    % Seleccionar archivo de audio de varios tipos
    [name, path] = uigetfile({'saveyour.wav'}, 'File Selector');
    
    if isequal(name, 0) || isequal(path, 0)
        disp("No seleccionó canción");
    else
        rutaPC = fullfile(path, name);
        
        % Leer el archivo de audio
        try
            [app.pistaOriginal, app.FS] = audioread(rutaPC);
            
            % Actualizar el estado de los indicadores
            app.Lamp_2.Color = [0.5, 0.5, 0.5]; % Color gris
            app.Lamp_3.Color = [1, 0, 0];       % Color rojo
            app.Lamp.Color = [0.5, 0.5, 0.5];   % Color gris
        catch ME
            % Mostrar mensaje de error en caso de fallo al leer el archivo
            disp(['Error al cargar el archivo de audio: ', ME.message]);
        end
    end


        end

        % Button pushed function: PlayButton
        function ReprodCancion(app, event)

            if app.isPaused
                play(app.cancion, app.muestra); % Reanudar desde la posición pausada
                app.isPlaying = true;
                app.isPaused = false;
            else
                evento(app)
                [sonido_rep] = FuncEqualizador(app);
                app.cancion = audioplayer(sonido_rep, app.FS);
                play(app.cancion); % Iniciar desde el principio
                app.isPlaying = true;
                
            end
        

        app.Lamp_2.Color=[0.5,0.5,0.5];
        app.Lamp_3.Color=[0.5,0.5,0.5];
        app.Lamp.Color=[0,1,0];
            
        end

        % Button pushed function: StopButton
        function PararCancion(app, event)
            stop(app.cancion);
            app.isPaused = false;
            app.isPlaying = false;

            cla(app.UIAxes, 'reset');
            cla(app.UIAxes_2, 'reset');
            app.Lamp_2.Color=[0.5,0.5,0.5];
            app.Lamp_3.Color=[1,0,0];
            app.Lamp.Color=[0.5,0.5,0.5];


        end

        % Button pushed function: PauseButton
        function PausarCancion(app, event)
            if app.isPlaying
                app.muestra = get(app.cancion,'CurrentSample');
                pause(app.cancion);
                app.isPlaying = false;
                app.isPaused = true;
            end

            app.Lamp_2.Color=[1, 1, 0];
            app.Lamp_3.Color=[0.5,0.5,0.5];
            app.Lamp.Color=[0.5,0.5,0.5];
        end

        % Value changing function: BalanceSlider
        function CambioBalanc(app, event)


    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
     
     
        end

        % Value changed function: BajosKnob
        function CambioBajKnob(app, event)
            if app.isPlaying == false && app.isPaused == false
                % Recalcular sin reproducir
                evento(app); % Recalcular la señal
                return;
            
            % Si está en pausa, recalcular sin reanudar la reproducción
            elseif app.isPaused == true
                evento(app);
            
            % Si está en reproducción, pausar y recalcular
            elseif app.isPlaying
                app.muestra = get(app.cancion, 'CurrentSample');
                pause(app.cancion);
                evento(app);  % Recalcular la señal
                play(app.cancion, app.muestra); % Reanudar desde la muestra actual
            end
     

        end

        % Value changed function: MediosKnob
        function CambioMedKnob(app, event)
            
            if app.isPlaying == false && app.isPaused == false
                % Recalcular sin reproducir
                evento(app); % Recalcular la señal
                return;
            
            % Si está en pausa, recalcular sin reanudar la reproducción
            elseif app.isPaused == true
                evento(app);
            
            % Si está en reproducción, pausar y recalcular
            elseif app.isPlaying
                app.muestra = get(app.cancion, 'CurrentSample');
                pause(app.cancion);
                evento(app);  % Recalcular la señal
                play(app.cancion, app.muestra); % Reanudar desde la muestra actual
            end
     

        end

        % Value changed function: AltosKnob
        function CambioAltosKnob(app, event)
            if app.isPlaying == false && app.isPaused == false
                % Recalcular sin reproducir
                evento(app); % Recalcular la señal
                return;
            
            % Si está en pausa, recalcular sin reanudar la reproducción
            elseif app.isPaused == true
                evento(app);
            
            % Si está en reproducción, pausar y recalcular
            elseif app.isPlaying
                app.muestra = get(app.cancion, 'CurrentSample');
                pause(app.cancion);
                evento(app);  % Recalcular la señal
                play(app.cancion, app.muestra); % Reanudar desde la muestra actual
            end
     
     
        end

        % Selection changed function: ValoresPredefinidosButtonGroup
        function ValoresPredefinidos10b(app, event)
            % Obtén el botón seleccionado
            selectedButton = app.ValoresPredefinidosButtonGroup.SelectedObject.Text;
            
            % Define las configuraciones en un containers.Map
            configuraciones = containers.Map(...
                {'Lounge', 'Voces', 'Clasica', 'Altos', 'Medios', 'Graves', 'Predeterminado'}, ...
                {...
                    [-3, -2, -1, 0, 1, 2, 3, 3, 6, 9], ...     % Lounge
                    [-2, -1, 0, 1, 1, -1, -1, -2, -3, -3], ... % Voces
                    [6, 4, 3, 0, -1, -2, -2, -3, -4, -6], ...  % Clasica
                    [3, 2, 1, 0, 0, -1, -1, -1, -3, -4], ...   % Altos
                    [-4, -3, -2, 0, 4, 5, 6, 4, 2, 0], ...     % Medios
                    [6, 5, 3, 1, 0, -2, -3, -4, -5, -6], ...   % Graves
                    [0, 0, 0, 0, 0, 0, 0, 0, 0, 0] ...         % Sin correcciones
                });
        
            % Verifica si la configuración seleccionada está en el containers.Map
            if isKey(configuraciones, selectedButton)
                % Obtén los valores para la configuración seleccionada
                valores = configuraciones(selectedButton);
                
                % Asigna los valores a los controles deslizantes
                app.SliderPasabanda1.Value = valores(1);
                app.SliderPasabanda2.Value  = valores(2);
                app.SliderPasabanda3.Value = valores(3);
                app.SliderPasabanda4.Value = valores(4);
                app.SliderPasabanda5.Value = valores(5);
                app.SliderPasabanda6.Value = valores(6);
                app.SliderPasabanda7.Value = valores(7);
                app.SliderPasabanda8.Value = valores(8);
                app.SliderPasabanda9.Value = valores(9);
                app.SliderPasabanda10.Value = valores(10);
            
            end
            
             %Manejo del audio según el estado actual
            if app.isPlaying
                app.muestra = get(app.cancion, 'CurrentSample');
                pause(app.cancion);
                evento(app);
                play(app.cancion, app.muestra);
            elseif app.isPaused
                evento(app);
            end
            
        end

        % Button pushed function: RestablecerKnobsButton
        function Restablecerknobs(app, event)
            app.AltosKnob.Value = 0;
            app.BajosKnob.Value = 0;
            app.MediosKnob.Value = 0;
            app.muestra = get(app.cancion, 'CurrentSample');
            pause(app.cancion);
            evento(app);  % Recalcular la señal
            play(app.cancion, app.muestra); % Reanudar desde la muestra actual
        end

        % Value changed function: SliderPasabanda1
        function CambioVal1(app, event)
           
             % Si no se ha dado clic en Play y no está en pausa, recalcular y no reproducir
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end


        end

        % Value changed function: SliderPasabanda2
        function CambioVal2(app, event)
    % Si no se ha dado clic en Play y no está en pausa, recalcular y no reproducir
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda3
        function CambioVal3(app, event)
    % Si no se ha dado clic en Play y no está en pausa, recalcular y no reproducir
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda4
        function CambioVal4(app, event)
    % Si no se ha dado clic en Play y no está en pausa, recalcular y no reproducir
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda5
        function CambioVal5(app, event)
             
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda6
        function CambioVal6(app, event)
            
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda7
        function CambioVal7(app, event)
    % Si no se ha dado clic en Play y no está en pausa, recalcular y no reproducir
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda8
        function CambioVal8(app, event)
             
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
        end

        % Value changed function: SliderPasabanda9
        function CambioVal9(app, event)
             
    if app.isPlaying == false && app.isPaused == false
        % Recalcular sin reproducir
        evento(app); % Recalcular la señal
        return;
    
    % Si está en pausa, recalcular sin reanudar la reproducción
    elseif app.isPaused == true
        evento(app);
    
    % Si está en reproducción, pausar y recalcular
    elseif app.isPlaying
        app.muestra = get(app.cancion, 'CurrentSample');
        pause(app.cancion);
        evento(app);  % Recalcular la señal
        play(app.cancion, app.muestra); % Reanudar desde la muestra actual
    end
            
        end

        % Value changed function: SliderPasabanda10
        function CambioVal10(app, event)
            
    
        end

        % Value changed function: BalanceSlider
        function BalanceSliderValueChanged(app, event)
            if app.isPlaying == false && app.isPaused == false
                % Recalcular sin reproducir
                evento(app); % Recalcular la señal
                return;
            
            % Si está en pausa, recalcular sin reanudar la reproducción
            elseif app.isPaused == true
                evento(app);
            
            % Si está en reproducción, pausar y recalcular
            elseif app.isPlaying
                app.muestra = get(app.cancion, 'CurrentSample');
                pause(app.cancion);
                evento(app);  % Recalcular la señal
                play(app.cancion, app.muestra); % Reanudar desde la muestra actual
            end
            
        end

        % Selection changed function: SelectordeEQButtonGroup
        function SelectordeEQButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectordeEQButtonGroup.SelectedObject;


            % Logica de pausa y reproducción
                if app.isPlaying == true && app.isPaused == false
                    % La canción está reproduciéndose, pausar antes de recalcular
                    app.muestra = get(app.cancion, 'CurrentSample'); % Guardar la posición actual
                    pause(app.cancion); % Pausar la canción
                    app.isPlaying = false; % Cambiar estado a pausado
                    
                    % Recalcular con el nuevo ecualizador seleccionado
                    evento(app);
                    
                    % Reanudar desde la muestra actual
                    play(app.cancion, app.muestra);
                    app.isPlaying = true; % Cambiar estado a reproduciendo
                    
                elseif app.isPaused == true
                    % Si está en pausa, recalcular sin reanudar
                    evento(app);
                    
                elseif app.isPlaying == false && app.isPaused == false
                    % Si no está reproduciéndose ni en pausa, simplemente recalcular
                    evento(app);
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta en frecuencia EQ 10')
            xlabel(app.UIAxes, 'Frecuencias')
            ylabel(app.UIAxes, 'dB''s')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Respuesta en frecuencia EQ 3')
            xlabel(app.UIAxes_2, 'Frecuencias')
            ylabel(app.UIAxes_2, 'dB''s')
            zlabel(app.UIAxes_2, 'Z')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @ReprodCancion, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PausarCancion, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @PararCancion, true);
            app.StopButton.Text = 'Stop';
            app.CargarPistaButton = uibutton(app.UIFigure, 'push');
            app.CargarPistaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarCancion, true);
            app.CargarPistaButton.Text = 'Cargar Pista';
            app.BalanceSliderLabel = uilabel(app.UIFigure);
            app.BalanceSliderLabel.Text = 'Balance';
            app.BalanceSlider = uislider(app.UIFigure);
            app.BalanceSlider.Limits = [-1 1];
            app.BalanceSlider.MajorTicks = [-1 0 1];
            app.BalanceSlider.ValueChangedFcn = createCallbackFcn(app, @BalanceSliderValueChanged, true);
            app.BalanceSlider.ValueChangingFcn = createCallbackFcn(app, @CambioBalanc, true);
            app.BajosKnobLabel = uilabel(app.UIFigure);
            app.BajosKnobLabel.Text = 'Bajos';
            app.BajosKnob = uiknob(app.UIFigure, 'continuous');
            app.BajosKnob.Limits = [-20 20];
            app.BajosKnob.ValueChangedFcn = createCallbackFcn(app, @CambioBajKnob, true);
            app.MediosKnobLabel = uilabel(app.UIFigure);
            app.MediosKnobLabel.Text = 'Medios';
            app.MediosKnob = uiknob(app.UIFigure, 'continuous');
            app.MediosKnob.Limits = [-20 20];
            app.MediosKnob.ValueChangedFcn = createCallbackFcn(app, @CambioMedKnob, true);
            app.AltosKnobLabel = uilabel(app.UIFigure);
            app.AltosKnobLabel.Text = 'Altos';
            app.AltosKnob = uiknob(app.UIFigure, 'continuous');
            app.AltosKnob.Limits = [-20 20];
            app.AltosKnob.ValueChangedFcn = createCallbackFcn(app, @CambioAltosKnob, true);
            app.SliderPasabanda1 = uislider(app.UIFigure);
            app.SliderPasabanda1.Limits = [-20 20];
            app.SliderPasabanda1.ValueChangedFcn = createCallbackFcn(app, @CambioVal1, true);
            app.SliderPasabanda2 = uislider(app.UIFigure);
            app.SliderPasabanda2.Limits = [-20 20];
            app.SliderPasabanda2.ValueChangedFcn = createCallbackFcn(app, @CambioVal2, true);
            app.SliderPasabanda3 = uislider(app.UIFigure);
            app.SliderPasabanda3.Limits = [-20 20];
            app.SliderPasabanda3.ValueChangedFcn = createCallbackFcn(app, @CambioVal3, true);
            app.SliderPasabanda4 = uislider(app.UIFigure);
            app.SliderPasabanda4.Limits = [-20 20];
            app.SliderPasabanda4.ValueChangedFcn = createCallbackFcn(app, @CambioVal4, true);
            app.SliderPasabanda5 = uislider(app.UIFigure);
            app.SliderPasabanda5.Limits = [-20 20];
            app.SliderPasabanda5.ValueChangedFcn = createCallbackFcn(app, @CambioVal5, true);
            app.SliderPasabanda6 = uislider(app.UIFigure);
            app.SliderPasabanda6.Limits = [-20 20];
            app.SliderPasabanda6.ValueChangedFcn = createCallbackFcn(app, @CambioVal6, true);
            app.SliderPasabanda7 = uislider(app.UIFigure);
            app.SliderPasabanda7.Limits = [-20 20];
            app.SliderPasabanda7.ValueChangedFcn = createCallbackFcn(app, @CambioVal7, true);
            app.SliderPasabanda8 = uislider(app.UIFigure);
            app.SliderPasabanda8.Limits = [-20 20];
            app.SliderPasabanda8.ValueChangedFcn = createCallbackFcn(app, @CambioVal8, true);
            app.SliderPasabanda9 = uislider(app.UIFigure);
            app.SliderPasabanda9.Limits = [-20 20];
            app.SliderPasabanda9.ValueChangedFcn = createCallbackFcn(app, @CambioVal9, true);
            app.SliderPasabanda10 = uislider(app.UIFigure);
            app.SliderPasabanda10.Limits = [-20 20];
            app.SliderPasabanda10.ValueChangedFcn = createCallbackFcn(app, @CambioVal10, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.SelectordeEQButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectordeEQButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectordeEQButtonGroupSelectionChanged, true);
            app.SelectordeEQButtonGroup.Title = 'Selector de EQ';
            app.EQ3Button = uiradiobutton(app.SelectordeEQButtonGroup);
            app.EQ3Button.Text = 'EQ 3';
            app.EQ3Button.Value = true;
            app.EQ10Button = uiradiobutton(app.SelectordeEQButtonGroup);
            app.EQ10Button.Text = 'EQ 10';
            app.ValoresPredefinidosButtonGroup = uibuttongroup(app.UIFigure);
            app.ValoresPredefinidosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ValoresPredefinidos10b, true);
            app.ValoresPredefinidosButtonGroup.Title = 'Valores Predefinidos';
            app.PredeterminadoButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.PredeterminadoButton.Text = 'Predeterminado';
            app.PredeterminadoButton.Value = true;
            app.GravesButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.GravesButton.Text = 'Graves';
            app.MediosButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.MediosButton.Text = 'Medios';
            app.AltosButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.AltosButton.Text = 'Altos';
            app.ClasicaButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.ClasicaButton.Text = 'Clasica';
            app.VocesButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.VocesButton.Text = 'Voces';
            app.LoungeButton = uitogglebutton(app.ValoresPredefinidosButtonGroup);
            app.LoungeButton.Text = 'Lounge';
            app.RestablecerKnobsButton = uibutton(app.UIFigure, 'push');
            app.RestablecerKnobsButton.ButtonPushedFcn = createCallbackFcn(app, @Restablecerknobs, true);
            app.RestablecerKnobsButton.Text = 'Restablecer Knobs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecualizador_SergioSebasv1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end